classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        voltageGraph      matlab.ui.control.UIAxes
        Label             matlab.ui.control.Label
        Slider            matlab.ui.control.Slider
        showSliderVal     matlab.ui.control.Label
        EndprogramButton  matlab.ui.control.Button
        LogintotxtButton  matlab.ui.control.Button
        SETPOINTrkLabel   matlab.ui.control.Label
        ForwardButton     matlab.ui.control.StateButton
        BackwardButton    matlab.ui.control.StateButton
    end


    properties (Access = private)
        a;        
        bContinue;
        
        dForwardPin = 'D11';
        dBackwardPin = 'D12';

    end

    methods (Access = private)
    
        function results = forward_motor(app)
            writePWMVoltage(app.a,app.dForwardPin,5);
            writePWMVoltage(app.a,app.dBackwardPin,0);
            
        end
        
        function results = stop_motor(app)
            writePWMVoltage(app.a,app.dForwardPin,0);
            writePWMVoltage(app.a,app.dBackwardPin,0);            
        end
        
        function results = back_motor(app)
            writePWMVoltage(app.a,app.dForwardPin,0);
            writePWMVoltage(app.a,app.dBackwardPin,5);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        % Initialice ARDUINO objects
        pause(5);
        
        app.a = arduino('/dev/cu.usbmodem1421','Mega2560');
        
        
        lectures = [];
        setPoint = [];
        err = [];
        xvec = [];
        app.voltageGraph.XLim = [0 50];
        app.voltageGraph.YLim = [-5 5];


        app.bContinue = true;
        
        while(app.bContinue)
            lectures = [];
            setPoint = [];
            err = [];
            for i = 1:50
                if(app.bContinue == false)
                    break;
                end
    
                xvec = [xvec,i ]
                v = app.a.readVoltage('A8');
                lectures = [lectures, v];
                setPoint = [setPoint, app.Slider.Value];
                err = [err, lectures(i) - setPoint(i)];
                grid(app.voltageGraph,'on');
                plot(app.voltageGraph, lectures,'--+','MarkerIndices',1:13:length(lectures),'LineWidth', 1,'color',[0 1 0]);
                hold(app.voltageGraph,'on');
                plot(app.voltageGraph, setPoint,'-*','MarkerIndices',1:7:length(lectures),'LineWidth', 1,'color',[1 0 0]);
                plot(app.voltageGraph, err,'-x','MarkerIndices',1:11:length(lectures),'LineWidth', 1,'color',[0 0 1]);
                legend(app.voltageGraph,'y(k)','r(k)','e(k)','Location','SouthEast');
                yticks(app.voltageGraph, (-5:0.5:5));
                hold(app.voltageGraph,'off');
                      
                % needed delay to draw plots, if removed app will crash
                pause(0.01);

            end
            
            if (app.bContinue == false)
                break;
            end
        end


        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            sliderValue = app.Slider.Value;
            app.showSliderVal.Text = num2str(sliderValue); 
            %plot(app.voltageGraph,sliderValue);
            %hold on;
        end

        % Button pushed function: EndprogramButton
        function EndprogramButtonPushed(app, event)
            app.bContinue = false;
        end

        % Callback function
        function PauseButtonPushed(app, event)
            if(app.bPause == false)
                app.bPause = true;
                app.PauseButton.Text = 'Continue';
            else
                app.bPause = false;
                app.PauseButton.Text = 'Pause';
            end
        end

        % Value changed function: ForwardButton
        function ForwardButtonValueChanged(app, event)
            value = app.ForwardButton.Value;
            if(value == true)
                app.ForwardButton.Text = 'STOP';
                forward_motor(app);
            else
                app.ForwardButton.Text = 'Forward';
                stop_motor(app);                
            end
        end

        % Value changed function: BackwardButton
        function BackwardButtonValueChanged(app, event)
            value = app.BackwardButton.Value;
            if(value == true)
                app.BackwardButton.Text = 'STOP';
                back_motor(app);
            else
                app.BackwardButton.Text = 'Backward';
                stop_motor(app);                
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.voltageGraph = uiaxes(app.UIFigure);
            title(app.voltageGraph, 'Control variables')
            xlabel(app.voltageGraph, 'k (samples)')
            ylabel(app.voltageGraph, 'Y')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 5];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 1;
            app.showSliderVal = uilabel(app.UIFigure);
            app.showSliderVal.Text = '';
            app.EndprogramButton = uibutton(app.UIFigure, 'push');
            app.EndprogramButton.ButtonPushedFcn = createCallbackFcn(app, @EndprogramButtonPushed, true);
            app.EndprogramButton.Text = 'End program';
            app.LogintotxtButton = uibutton(app.UIFigure, 'push');
            app.LogintotxtButton.Text = 'Log into txt';
            app.SETPOINTrkLabel = uilabel(app.UIFigure);
            app.SETPOINTrkLabel.Text = {'SET POINT '; '       r(k)'};
            app.ForwardButton = uibutton(app.UIFigure, 'state');
            app.ForwardButton.ValueChangedFcn = createCallbackFcn(app, @ForwardButtonValueChanged, true);
            app.ForwardButton.Text = 'Forward';
            app.BackwardButton = uibutton(app.UIFigure, 'state');
            app.BackwardButton.ValueChangedFcn = createCallbackFcn(app, @BackwardButtonValueChanged, true);
            app.BackwardButton.Text = 'Backward';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end